classdef flexx_point_cloud_stitch_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NavigationTrustLabel           matlab.ui.control.Label
        nav_trust_slider               matlab.ui.control.Slider
        ProcessButton                  matlab.ui.control.Button
        status_msg                     matlab.ui.control.Label
        nav_trust_field                matlab.ui.control.NumericEditField
        BrowserrffileButton            matlab.ui.control.Button
        BrowsenavdatafileButton        matlab.ui.control.Button
        rrf_path_label                 matlab.ui.control.Label
        txt_path_label                 matlab.ui.control.Label
        KillButton                     matlab.ui.control.Button
        CalibrationTimeEditFieldLabel  matlab.ui.control.Label
        CalibrationTimeEditField       matlab.ui.control.NumericEditField
        Nav_ICPSimultaneousCheckBox    matlab.ui.control.CheckBox
        ViewingPanel                   matlab.ui.container.Panel
        ViewButton                     matlab.ui.control.Button
        MovieCheckBox                  matlab.ui.control.CheckBox
        TabGroup                       matlab.ui.container.TabGroup
        ColorTab                       matlab.ui.container.Tab
        rEditFieldLabel                matlab.ui.control.Label
        rEditField                     matlab.ui.control.NumericEditField
        gEditFieldLabel                matlab.ui.control.Label
        gEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        ColorButtonGroup               matlab.ui.container.ButtonGroup
        RedButton                      matlab.ui.control.RadioButton
        GreenButton                    matlab.ui.control.RadioButton
        BlueButton                     matlab.ui.control.RadioButton
        CustomButton                   matlab.ui.control.RadioButton
        PlotShiftTab                   matlab.ui.container.Tab
        xEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        zEditFieldLabel                matlab.ui.control.Label
        zEditField                     matlab.ui.control.NumericEditField
        ZerooutButton                  matlab.ui.control.Button
        RxEditFieldLabel               matlab.ui.control.Label
        RxEditField                    matlab.ui.control.NumericEditField
        RyEditFieldLabel               matlab.ui.control.Label
        RyEditField                    matlab.ui.control.NumericEditField
        RzEditFieldLabel               matlab.ui.control.Label
        RzEditField                    matlab.ui.control.NumericEditField
        RotateLabel                    matlab.ui.control.Label
        TranslateLabel                 matlab.ui.control.Label
        ViewerTab                      matlab.ui.container.Tab
        x_viewEditFieldLabel           matlab.ui.control.Label
        x_viewEditField                matlab.ui.control.NumericEditField
        y_viewEditFieldLabel           matlab.ui.control.Label
        y_viewEditField                matlab.ui.control.NumericEditField
        z_viewEditFieldLabel           matlab.ui.control.Label
        z_viewEditField                matlab.ui.control.NumericEditField
        ButtonGroup                    matlab.ui.container.ButtonGroup
        CircleAroundButton             matlab.ui.control.RadioButton
        CircleDipButton                matlab.ui.control.RadioButton
        Button3                        matlab.ui.control.RadioButton
        ZoomSliderLabel                matlab.ui.control.Label
        ZoomSlider                     matlab.ui.control.Slider
        StartFrameEditFieldLabel       matlab.ui.control.Label
        StartFrameEditField            matlab.ui.control.NumericEditField
        EndFrameEditFieldLabel         matlab.ui.control.Label
        EndFrameEditField              matlab.ui.control.NumericEditField
        RotationsCheckBox              matlab.ui.control.CheckBox
        TranslationsCheckBox           matlab.ui.control.CheckBox
        RefreshDataCheckBox            matlab.ui.control.CheckBox
        SaveCloudButton                matlab.ui.control.Button
        SceneNameEditFieldLabel        matlab.ui.control.Label
        SceneNameEditField             matlab.ui.control.EditField
        LoadButton                     matlab.ui.control.Button
        mat_path                       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.nav_trust_field.Value = app.nav_trust_slider.Value;
            global nav_file_name pico_file_name
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            global nav_file_name pico_file_name
            app.status_msg.Text = 'Filtering navigation data...';
            calibration_period = app.CalibrationTimeEditField.Value; % sec
%             [time , ~, ~, sensor_pos, sensor_att] = comp_filt_fun(nav_file_name,calibration_period);

            threshold_factor=3; fc = 0.03; 
            [time , ~, ~, sensor_pos, sensor_att] = navdata_filtering(nav_file_name,calibration_period,threshold_factor,fc);
            
            global ptCloudScene
            nav_data_trust = app.nav_trust_slider.Value/100;
            app.status_msg.Text = 'Status: Filtering, synchronizing, rendering clouds, stitching...';
            pause(0.0001)
            
            
            start_frame = app.StartFrameEditField.Value;
            end_frame = app.EndFrameEditField.Value;
            if app.Nav_ICPSimultaneousCheckBox.Value
                ptCloudScene = process_scene(pico_file_name, time, sensor_pos, sensor_att, nav_data_trust, start_frame, end_frame, app.RotationsCheckBox.Value, app.TranslationsCheckBox.Value, app.RefreshDataCheckBox.Value);
            else
                ptCloudScene = process_scene_2(pico_file_name, time, sensor_pos, sensor_att,nav_data_trust,start_frame,end_frame,app.RotationsCheckBox.Value, app.TranslationsCheckBox.Value,app.RefreshDataCheckBox.Value);
            end
            
            app.RefreshDataCheckBox.Value = 0;
            app.status_msg.Text = 'Status: Ready';
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            global ptCloudScene
            shift = [app.xEditField.Value, app.yEditField.Value, app.zEditField.Value];
            shift_matrix = eye(4);
            shift_matrix(1:3,4) = shift;
            translation=affine3d(shift_matrix');
            myptCloudScene = pctransform(ptCloudScene,translation);
%             myptCloudScene = pctransform(myptCloudScene, affine3d(euler2rot_func([0 -22 0])));
%           7_8_clean7 with simultaneous
%             myptCloudScene = pctransform(myptCloudScene, affine3d(euler2rot_func([-7 55 -14])));
%           7_8_clean7 without simultaneous
            rotation = [app.RxEditField.Value app.RyEditField.Value app.RzEditField.Value];
            myptCloudScene = pctransform(myptCloudScene, affine3d(euler2rot_func(rotation)));
            h = figure('units','normalized','outerposition',[0 0 1 1]);
%             set(h, 'MenuBar', 'none');
%             set(h, 'ToolBar', 'none');
            pointscolor=uint8(zeros(myptCloudScene.Count,3));
            pointscolor(:,1)=app.rEditField.Value;
            pointscolor(:,2)=app.gEditField.Value;
            pointscolor(:,3)=app.bEditField.Value;  
            myptCloudScene.Color = pointscolor; 
            myptCloudScene.Intensity = [];
            
            pcshow(myptCloudScene,'VerticalAxis','Y', 'VerticalAxisDir', 'Down')
%             set(h, 'color', 'k')
            set(gca, 'color', 'k'); 
            set(gca, 'xcolor', 'b'); set(gca, 'ycolor', 'b')
            set(gca, 'zcolor', 'b');
            xlabel('x');ylabel('y');zlabel('z');
            camtarget([0 0 7])
            axis vis3d
            camzoom(app.ZoomSlider.Value)
            if app.MovieCheckBox.Value
                camTarg = [0, 0, -1];
                camtarget(camTarg);
                set(gca,'CameraUpVectorMode','auto')
                set(gca,'CameraUpVector',[0 -1 0])
                ii =  0;
                if app.CircleAroundButton.Value
                    while ishandle(h)
                        campos([-30*cos(ii/16) app.y_viewEditField.Value -30*sin(ii/16)])
                        pause(.01)
                        ii = ii + pi/200;
% % %                         camzoom(20*ii/200)
                    end 
                elseif app.CircleDipButton.Value
                    while ishandle(h)
                        campos([-30*cos(ii/16) 8*sin(ii/12) -30*sin(ii/12)])
                        pause(.01)
                        ii = ii + pi/250;
                    end 
                end
                
                
            end
            
            
        end

        % Value changed function: nav_trust_slider
        function nav_trust_sliderValueChanged(app, event)
            value = app.nav_trust_slider.Value;
            app.nav_trust_field.Value = value; 
        end

        % Value changed function: nav_trust_field
        function nav_trust_fieldValueChanged(app, event)
            value = app.nav_trust_field.Value;
            app.nav_trust_slider.Value = value;
        end

        % Button pushed function: BrowserrffileButton
        function BrowserrffileButtonPushed(app, event)
            global pico_file_name
            [file,path] = uigetfile('*.rrf');
            if isequal(file,0)
               disp('User selected Cancel');
               app.rrf_path_label.Text = 'No file selected.';
            else
               disp(['User selected ', fullfile(path,file)]);
               app.rrf_path_label.Text = file;
               pico_file_name = file; 
            end
            app.RefreshDataCheckBox.Value =1;
        end

        % Button pushed function: BrowsenavdatafileButton
        function BrowsenavdatafileButtonPushed(app, event)
            global nav_file_name
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
               disp('User selected Cancel');
               app.rrf_path_label.Text = 'No file selected.';
            else
               disp(['User selected ', fullfile(path,file)]);
               app.txt_path_label.Text = file;
               nav_file_name = file; 
            end
            app.RefreshDataCheckBox.Value =1;
        end

        % Selection changed function: ColorButtonGroup
        function ColorButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColorButtonGroup.SelectedObject;
            if app.RedButton.Value == 1
                app.rEditField.Value = 255;
                app.gEditField.Value = 0;
                app.bEditField.Value = 0;
                
            elseif app.GreenButton.Value ==1 
                app.rEditField.Value = 0;
                app.gEditField.Value = 255;
                app.bEditField.Value = 0;
                
            elseif app.BlueButton.Value ==1
                app.rEditField.Value = 0;
                app.gEditField.Value = 0;
                app.bEditField.Value = 255;
                

            elseif app.ColorButtonGroup ==1

                
            end
        end

        % Button pushed function: KillButton
        function KillButtonPushed(app, event)
            quit force
        end

        % Callback function
        function MovieButtonPushed(app, event)
% %             iter = 1; 
% %             while ishandle(h)
% %                  view([iter -60])
% %                  iter = iter + 1;
% %             end 
        end

        % Value changed function: MovieCheckBox
        function MovieCheckBoxValueChanged(app, event)
%             value = app.MovieCheckBox.Value;
%             iter = 1; 
%             while value && ishandle(gcf)
%                 if ~value || ~ishandle(gcf)
%                     break
%                 end
%                 ax = gca; 
%                 view(ax,[iter -90])
%                 iter = iter + 1;
%             end
        end

        % Value changed function: Nav_ICPSimultaneousCheckBox
        function Nav_ICPSimultaneousCheckBoxValueChanged(app, event)
            value = app.Nav_ICPSimultaneousCheckBox.Value;
            app.nav_trust_slider.Visible = value;
            app.NavigationTrustLabel.Visible = value;
            app.nav_trust_field.Visible = value;
        end

        % Button pushed function: ZerooutButton
        function ZerooutButtonPushed(app, event)
            app.xEditField.Value = 0;
            app.yEditField.Value = 0;
            app.zEditField.Value = 0;
            app.RxEditField.Value = 0; 
            app.RyEditField.Value = 0; 
            app.RzEditField.Value = 0; 
        end

        % Callback function
        function GrabDataButtonPushed(app, event)
            
        end

        % Button pushed function: SaveCloudButton
        function SaveCloudButtonPushed(app, event)
        global ptCloudScene    
        save(app.SceneNameEditField.Value,'ptCloudScene')
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            global ptCloudScene
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
               disp('User selected Cancel');
               app.mat_path.Text = 'No file selected.';
            else
               disp(['User selected ', fullfile(path,file)]);
               app.mat_path.Text = file;
               load(file,'merge_scene')
               disp(class(merge_scene))
               ptCloudScene = merge_scene;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NavigationTrustLabel = uilabel(app.UIFigure);
            app.NavigationTrustLabel.Text = 'Navigation Trust %';
            app.nav_trust_slider = uislider(app.UIFigure);
            app.nav_trust_slider.ValueChangedFcn = createCallbackFcn(app, @nav_trust_sliderValueChanged, true);
            app.nav_trust_slider.Value = 90;
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.status_msg = uilabel(app.UIFigure);
            app.status_msg.Text = '';
            app.nav_trust_field = uieditfield(app.UIFigure, 'numeric');
            app.nav_trust_field.ValueChangedFcn = createCallbackFcn(app, @nav_trust_fieldValueChanged, true);
            app.nav_trust_field.Value = 90;
            app.BrowserrffileButton = uibutton(app.UIFigure, 'push');
            app.BrowserrffileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowserrffileButtonPushed, true);
            app.BrowserrffileButton.Text = 'Browse rrf file';
            app.BrowsenavdatafileButton = uibutton(app.UIFigure, 'push');
            app.BrowsenavdatafileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsenavdatafileButtonPushed, true);
            app.BrowsenavdatafileButton.Text = 'Browse nav data file';
            app.rrf_path_label = uilabel(app.UIFigure);
            app.rrf_path_label.Text = '';
            app.txt_path_label = uilabel(app.UIFigure);
            app.txt_path_label.Text = '';
            app.KillButton = uibutton(app.UIFigure, 'push');
            app.KillButton.ButtonPushedFcn = createCallbackFcn(app, @KillButtonPushed, true);
            app.KillButton.Text = 'Kill';
            app.CalibrationTimeEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationTimeEditFieldLabel.Text = 'Calibration Time';
            app.CalibrationTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalibrationTimeEditField.Value = 30;
            app.Nav_ICPSimultaneousCheckBox = uicheckbox(app.UIFigure);
            app.Nav_ICPSimultaneousCheckBox.ValueChangedFcn = createCallbackFcn(app, @Nav_ICPSimultaneousCheckBoxValueChanged, true);
            app.Nav_ICPSimultaneousCheckBox.Text = 'Nav_ICP Simultaneous';
            app.Nav_ICPSimultaneousCheckBox.Value = true;
            app.ViewingPanel = uipanel(app.UIFigure);
            app.ViewingPanel.Title = 'Viewing';
            app.ViewButton = uibutton(app.ViewingPanel, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.MovieCheckBox = uicheckbox(app.ViewingPanel);
            app.MovieCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox.Text = 'Movie';
            app.TabGroup = uitabgroup(app.ViewingPanel);
            app.ColorTab = uitab(app.TabGroup);
            app.ColorTab.Title = 'Color';
            app.rEditFieldLabel = uilabel(app.ColorTab);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.ColorTab, 'numeric');
            app.gEditFieldLabel = uilabel(app.ColorTab);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.ColorTab, 'numeric');
            app.bEditFieldLabel = uilabel(app.ColorTab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.ColorTab, 'numeric');
            app.bEditField.Value = 255;
            app.ColorButtonGroup = uibuttongroup(app.ColorTab);
            app.ColorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorButtonGroupSelectionChanged, true);
            app.ColorButtonGroup.Title = 'Color';
            app.RedButton = uiradiobutton(app.ColorButtonGroup);
            app.RedButton.Text = 'Red';
            app.GreenButton = uiradiobutton(app.ColorButtonGroup);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uiradiobutton(app.ColorButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.BlueButton.Value = true;
            app.CustomButton = uiradiobutton(app.ColorButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.PlotShiftTab = uitab(app.TabGroup);
            app.PlotShiftTab.Title = 'Plot Shift';
            app.xEditFieldLabel = uilabel(app.PlotShiftTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.PlotShiftTab, 'numeric');
            app.yEditFieldLabel = uilabel(app.PlotShiftTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.PlotShiftTab, 'numeric');
            app.zEditFieldLabel = uilabel(app.PlotShiftTab);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.PlotShiftTab, 'numeric');
            app.ZerooutButton = uibutton(app.PlotShiftTab, 'push');
            app.ZerooutButton.ButtonPushedFcn = createCallbackFcn(app, @ZerooutButtonPushed, true);
            app.ZerooutButton.Text = 'Zero out';
            app.RxEditFieldLabel = uilabel(app.PlotShiftTab);
            app.RxEditFieldLabel.Text = 'Rx';
            app.RxEditField = uieditfield(app.PlotShiftTab, 'numeric');
            app.RyEditFieldLabel = uilabel(app.PlotShiftTab);
            app.RyEditFieldLabel.Text = 'Ry';
            app.RyEditField = uieditfield(app.PlotShiftTab, 'numeric');
            app.RzEditFieldLabel = uilabel(app.PlotShiftTab);
            app.RzEditFieldLabel.Text = 'Rz';
            app.RzEditField = uieditfield(app.PlotShiftTab, 'numeric');
            app.RotateLabel = uilabel(app.PlotShiftTab);
            app.RotateLabel.Text = 'Rotate';
            app.TranslateLabel = uilabel(app.PlotShiftTab);
            app.TranslateLabel.Text = 'Translate';
            app.ViewerTab = uitab(app.TabGroup);
            app.ViewerTab.Title = 'Viewer';
            app.x_viewEditFieldLabel = uilabel(app.ViewerTab);
            app.x_viewEditFieldLabel.Text = 'x_view';
            app.x_viewEditField = uieditfield(app.ViewerTab, 'numeric');
            app.y_viewEditFieldLabel = uilabel(app.ViewerTab);
            app.y_viewEditFieldLabel.Text = 'y_view';
            app.y_viewEditField = uieditfield(app.ViewerTab, 'numeric');
            app.y_viewEditField.Value = -7;
            app.z_viewEditFieldLabel = uilabel(app.ViewerTab);
            app.z_viewEditFieldLabel.Text = 'z_view';
            app.z_viewEditField = uieditfield(app.ViewerTab, 'numeric');
            app.ButtonGroup = uibuttongroup(app.ViewerTab);
            app.ButtonGroup.Title = 'Button Group';
            app.CircleAroundButton = uiradiobutton(app.ButtonGroup);
            app.CircleAroundButton.Text = 'Circle Around';
            app.CircleAroundButton.Value = true;
            app.CircleDipButton = uiradiobutton(app.ButtonGroup);
            app.CircleDipButton.Text = 'CircleDip';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.ZoomSliderLabel = uilabel(app.ViewingPanel);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.ViewingPanel);
            app.ZoomSlider.Limits = [0 10];
            app.ZoomSlider.Value = 2;
            app.StartFrameEditFieldLabel = uilabel(app.UIFigure);
            app.StartFrameEditFieldLabel.Text = 'Start Frame';
            app.StartFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartFrameEditField.Value = 15;
            app.EndFrameEditFieldLabel = uilabel(app.UIFigure);
            app.EndFrameEditFieldLabel.Text = 'End Frame';
            app.EndFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndFrameEditField.Value = 100;
            app.RotationsCheckBox = uicheckbox(app.UIFigure);
            app.RotationsCheckBox.Text = 'Rotations';
            app.RotationsCheckBox.Value = true;
            app.TranslationsCheckBox = uicheckbox(app.UIFigure);
            app.TranslationsCheckBox.Text = 'Translations';
            app.RefreshDataCheckBox = uicheckbox(app.UIFigure);
            app.RefreshDataCheckBox.Text = 'Refresh Data';
            app.RefreshDataCheckBox.Value = true;
            app.SaveCloudButton = uibutton(app.UIFigure, 'push');
            app.SaveCloudButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCloudButtonPushed, true);
            app.SaveCloudButton.Text = 'Save Cloud';
            app.SceneNameEditFieldLabel = uilabel(app.UIFigure);
            app.SceneNameEditFieldLabel.Text = 'Scene Name';
            app.SceneNameEditField = uieditfield(app.UIFigure, 'text');
            app.SceneNameEditField.Value = '7_11_hallway2_electric_boogaloo_stitch1.mat';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.mat_path = uilabel(app.UIFigure);
            app.mat_path.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flexx_point_cloud_stitch_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end